classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        EStopButton                   matlab.ui.control.StateButton
        PowerSwitchLabel              matlab.ui.control.Label
        PowerSwitch                   matlab.ui.control.Switch
        BlockSliderLabel              matlab.ui.control.Label
        BlockSlider                   matlab.ui.control.Slider
        Lamp                          matlab.ui.control.Lamp
        StartButton                   matlab.ui.control.StateButton
        LightCurtainBlockSliderLabel  matlab.ui.control.Label
        LightCurtainBlockSlider       matlab.ui.control.Slider
        ResetLightButton              matlab.ui.control.StateButton
        RightPanel                    matlab.ui.container.Panel
        Joint1Slider_2Label           matlab.ui.control.Label
        Joint1Slider                  matlab.ui.control.Slider
        Joint2Slider_2Label           matlab.ui.control.Label
        Joint2Slider                  matlab.ui.control.Slider
        Joint3Slider_2Label           matlab.ui.control.Label
        Joint3Slider                  matlab.ui.control.Slider
        Joint4Slider_2Label           matlab.ui.control.Label
        Joint4Slider                  matlab.ui.control.Slider
        Joint5Slider_2Label           matlab.ui.control.Label
        Joint5Slider                  matlab.ui.control.Slider
        Joint6Slider_2Label           matlab.ui.control.Label
        Joint7Slider_2Label           matlab.ui.control.Label
        Joint7Slider                  matlab.ui.control.Slider
        TeachButton                   matlab.ui.control.StateButton
        Joint6Slider                  matlab.ui.control.Slider
        RadJoint1                     matlab.ui.control.NumericEditField
        RadiansLabel                  matlab.ui.control.Label
        DegreesLabel                  matlab.ui.control.Label
        DegJoint1                     matlab.ui.control.NumericEditField
        RadJoint2                     matlab.ui.control.NumericEditField
        RadJoint3                     matlab.ui.control.NumericEditField
        RadJoint4                     matlab.ui.control.NumericEditField
        RadJoint5                     matlab.ui.control.NumericEditField
        RadJoint6                     matlab.ui.control.NumericEditField
        RadJoint7                     matlab.ui.control.NumericEditField
        DegJoint2                     matlab.ui.control.NumericEditField
        DegJoint3                     matlab.ui.control.NumericEditField
        DegJoint4                     matlab.ui.control.NumericEditField
        DegJoint5                     matlab.ui.control.NumericEditField
        DegJoint6                     matlab.ui.control.NumericEditField
        DegJoint7                     matlab.ui.control.NumericEditField
        XEditFieldLabel               matlab.ui.control.Label
        XEditField                    matlab.ui.control.NumericEditField
        YEditFieldLabel               matlab.ui.control.Label
        YEditField                    matlab.ui.control.NumericEditField
        ZEditFieldLabel               matlab.ui.control.Label
        ZEditField                    matlab.ui.control.NumericEditField
        EndEffectorLabel              matlab.ui.control.Label
        CalculateButton               matlab.ui.control.Button
        ModeButton                    matlab.ui.control.StateButton
        EditFieldLabel                matlab.ui.control.Label
        EditField                     matlab.ui.control.NumericEditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        EndEffector         % Description
    end
    
    methods (Access = public)
        function state = GetEStopValue(app)
            state = app.EStopButton.Value;
        end
        
        function powerState = GetPowerValue(app)
            powerState = app.PowerSwitch.Value;
        end
        
        function teachMode = GetTeachModeValue(app)
            teachMode = app.TeachButton.Value;
        end
        
        function teachMode2 = GetTeachMode2Value(app)
            teachMode2 = app.ModeButton.Value;
        end
        
        function startValue = GetStartValue(app)
            startValue = app.StartButton.Value;
        end
        
        function resetButtonValue = GetResetValue(app)
            resetButtonValue = app.ResetLightButton.Value;
        end
        
        function joint1 = GetJoint1Value(app)
            joint1 = app.RadJoint1.Value;
        end
        
        function joint2 = GetJoint2Value(app)
            joint2 = app.RadJoint2.Value;
        end
        
        function joint3 = GetJoint3Value(app)
            joint3 = app.RadJoint3.Value;
        end
        
        function joint4 = GetJoint4Value(app)
            joint4 = app.RadJoint4.Value;
        end
        
        function joint5 = GetJoint5Value(app)
            joint5 = app.RadJoint5.Value;
        end
        
        function joint6 = GetJoint6Value(app)
            joint6 = app.RadJoint6.Value;
        end
        
        function joint7 = GetJoint7Value(app)
            joint7 = app.RadJoint7.Value;
        end
        
        function endEffector = CalculateEndEffector(app)
            name = 'Robot';
            fetchBase = transl(0, 0, 0);
            link1 = Link('d',0.107511,'a',0.120238,'alpha',pi/2,'qlim',[deg2rad(-92),deg2rad(92)]); %shoulderpan
            link2 = Link('d',0,'a',0,'alpha',pi/2,'qlim',[deg2rad(-87),deg2rad(70)], 'offset', deg2rad(90)); % shoulder lift
            link3 = Link('d',0.35,'a',0,'alpha',-pi/2); % upperarm roll
            link4 = Link('d',0,'a',0,'alpha',pi/2,'qlim',[deg2rad(-129),deg2rad(129)]); %elbow flex
            link5 = Link('d',0.315,'a',0,'alpha',pi/2); %forearm roll
            link6 = Link('d',0,'a',0,'alpha',-pi/2,'qlim',[deg2rad(-125),deg2rad(125)]); %wrist flex
            link7 = Link('d',0.15,'a',0,'alpha',0); %wrist roll
            robot = SerialLink([link1 link2 link3 link4 link5 link6 link7], 'name', name, 'base', fetchBase);
            joint1 = app.GetJoint1Value();
            joint2 = app.GetJoint2Value();
            joint3 = app.GetJoint3Value();
            joint4 = app.GetJoint4Value();
            joint5 = app.GetJoint5Value();
            joint6 = app.GetJoint6Value();
            joint7 = app.GetJoint7Value();
            qMatrix = [joint1 joint2 joint3 joint4 joint5 joint6 joint7];
            endEffector = robot.fkine(qMatrix);
        end
        
        function result = GetBlockSlider(app)
            result = app.BlockSlider.Value;
        end
        
        function result = GetLightBlockSlider(app)
            result = app.LightCurtainBlockSlider.Value;
        end
        
        function joints = GetJointConfig(app, endEffector)
            name = 'Robot';
            fetchBase = transl(0, 0, 0.52);
            link1 = Link('d',0.107511,'a',0.120238,'alpha',pi/2,'qlim',[deg2rad(-92),deg2rad(92)]); %shoulderpan
            link2 = Link('d',0,'a',0,'alpha',pi/2,'qlim',[deg2rad(-87),deg2rad(70)], 'offset', deg2rad(90)); % shoulder lift
            link3 = Link('d',0.35,'a',0,'alpha',-pi/2); % upperarm roll
            link4 = Link('d',0,'a',0,'alpha',pi/2,'qlim',[deg2rad(-129),deg2rad(129)]); %elbow flex
            link5 = Link('d',0.315,'a',0,'alpha',pi/2); %forearm roll
            link6 = Link('d',0,'a',0,'alpha',-pi/2,'qlim',[deg2rad(-125),deg2rad(125)]); %wrist flex
            link7 = Link('d',0.15,'a',0,'alpha',0); %wrist roll
            robot = SerialLink([link1 link2 link3 link4 link5 link6 link7], 'name', name, 'base', fetchBase);
            joints = robot.ikcon(endEffector);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: PowerSwitch
        function PowerSwitchValueChanged(app, event)
            value = app.PowerSwitch.Value;
            if (value == "On")
                app.Lamp.Color = [0,1,0];
            elseif (value == "Off")
                app.Lamp.Color = [1,0,0];
            end
        end

        % Value changed function: EStopButton
        function EStopButtonValueChanged(app, event)
            value = app.EStopButton.Value;
            if (value == 1)
                disp('Stop');
            elseif (value == 0)
                disp('Continue');
            end
        end

        % Value changed function: BlockSlider
        function BlockSliderValueChanged(app, event)
            value = app.BlockSlider.Value;            
        end

        % Value changing function: BlockSlider
        function BlockSliderValueChanging(app, event)
            changingValue = event.Value;
            app.BlockSlider.Value = changingValue;
        end

        % Value changed function: LightCurtainBlockSlider
        function LightCurtainBlockSliderValueChanged(app, event)
            value = app.LightCurtainBlockSlider.Value;
            event.Value;
            
        end

        % Value changing function: LightCurtainBlockSlider
        function LightCurtainBlockSliderValueChanging(app, event)
            changingValue = event.Value;
            app.LightCurtainBlockSlider.Value = changingValue;
        end

        % Value changed function: ResetLightButton
        function ResetLightButtonValueChanged(app, event)
            value = app.ResetLightButton.Value;            
        end

        % Value changed function: TeachButton
        function TeachButtonValueChanged(app, event)
            value = app.TeachButton.Value;
        
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            value = app.StartButton.Value;
            
        end

        % Value changed function: DegJoint1
        function DegJoint1ValueChanged(app, event)
            if(app.ModeButton.Value == 0)
                if(app.DegJoint1.Value > 92)
                    app.DegJoint1.Value = 92;
                end
                if(app.DegJoint1.Value < -92)
                    app.DegJoint1.Value = -92;
                end
                app.Joint1Slider.Value = app.DegJoint1.Value;
                app.DegJoint1.Value = app.Joint1Slider.Value;
                app.RadJoint1.Value = deg2rad(app.DegJoint1.Value);
                app.RadJoint1.Value = deg2rad(app.Joint1Slider.Value);
                XEditFieldValueChanged(app);
                YEditFieldValueChanged(app);
                ZEditFieldValueChanged(app);
            end
            if(app.ModeButton.Value == 1)
                CalculateButtonPushed(app, event)
%                 app.Joint1Slider.Value = app.DegJoint1.Value;
%                 app.RadJoint1.Value = deg2rad(app.DegJoint1.Value);
            end
        end

        % Value changed function: Joint1Slider
        function Joint1SliderValueChanged(app, event)
            if(app.ModeButton.Value == 0)
                app.DegJoint1.Value = app.Joint1Slider.Value;
                app.Joint1Slider.Value = app.DegJoint1.Value;
                app.RadJoint1.Value = deg2rad(app.DegJoint1.Value);
                app.RadJoint1.Value = deg2rad(app.Joint1Slider.Value);
                XEditFieldValueChanged(app);
                YEditFieldValueChanged(app);
                ZEditFieldValueChanged(app);
            end
            if(app.ModeButton.Value == 1)
                CalculateButtonPushed(app, event)
            end
        end

        % Value changed function: DegJoint2
        function DegJoint2ValueChanged(app, event)
            if(app.ModeButton.Value == 0)
                if(app.DegJoint2.Value > 70)
                    app.DegJoint2.Value = 70;
                end
                if(app.DegJoint2.Value < -87)
                    app.DegJoint2.Value = -87;
                end
                app.Joint2Slider.Value = app.DegJoint2.Value;
                app.DegJoint2.Value = app.Joint2Slider.Value;
                app.RadJoint2.Value = deg2rad(app.DegJoint2.Value);
                app.RadJoint2.Value = deg2rad(app.Joint2Slider.Value);
                XEditFieldValueChanged(app);
                YEditFieldValueChanged(app);
                ZEditFieldValueChanged(app);
            end
            if(app.ModeButton.Value == 1)
                CalculateButtonPushed(app, event)
%                 app.Joint2Slider.Value = app.DegJoint2.Value;
%                 app.RadJoint2.Value = deg2rad(app.DegJoint2.Value);
            end
        end

        % Value changed function: Joint2Slider
        function Joint2SliderValueChanged(app, event)
            if(app.ModeButton.Value == 0)
                app.DegJoint2.Value = app.Joint2Slider.Value;
                app.Joint2Slider.Value = app.DegJoint2.Value;
                app.RadJoint2.Value = deg2rad(app.DegJoint2.Value);
                app.RadJoint2.Value = deg2rad(app.Joint2Slider.Value);
                XEditFieldValueChanged(app);
                YEditFieldValueChanged(app);
                ZEditFieldValueChanged(app);
            end
            if(app.ModeButton.Value == 1)
                CalculateButtonPushed(app, event)
            end
        end

        % Value changed function: DegJoint3
        function DegJoint3ValueChanged(app, event)
            if(app.ModeButton.Value == 0)
                if(app.DegJoint3.Value > 180)
                    app.DegJoint3.Value = 180;
                end
                if(app.DegJoint3.Value < -180)
                    app.DegJoint3.Value = -180;
                end
                app.Joint3Slider.Value = app.DegJoint3.Value;
                app.DegJoint3.Value = app.Joint3Slider.Value;
                app.RadJoint3.Value = deg2rad(app.DegJoint3.Value);
                app.RadJoint3.Value = deg2rad(app.Joint3Slider.Value);
                XEditFieldValueChanged(app);
                YEditFieldValueChanged(app);
                ZEditFieldValueChanged(app);
            end
            if(app.ModeButton.Value == 1)
                CalculateButtonPushed(app, event)
%                 app.Joint3Slider.Value = app.DegJoint3.Value;
%                 app.RadJoint3.Value = deg2rad(app.DegJoint3.Value);
            end
        end

        % Value changed function: Joint3Slider
        function Joint3SliderValueChanged(app, event)
            if(app.ModeButton.Value == 0)
                app.DegJoint3.Value = app.Joint3Slider.Value;
                app.Joint3Slider.Value = app.DegJoint3.Value;
                app.RadJoint3.Value = deg2rad(app.DegJoint3.Value);
                app.RadJoint3.Value = deg2rad(app.Joint3Slider.Value);
                XEditFieldValueChanged(app);
                YEditFieldValueChanged(app);
                ZEditFieldValueChanged(app);
            end
            if(app.ModeButton.Value == 1)
                CalculateButtonPushed(app, event)
            end
        end

        % Value changed function: DegJoint4
        function DegJoint4ValueChanged(app, event)
            if(app.ModeButton.Value == 0)
                if(app.DegJoint4.Value > 129)
                    app.DegJoint4.Value = 129;
                end
                if(app.DegJoint4.Value < -129)
                    app.DegJoint4.Value = -129;
                end
                app.Joint4Slider.Value = app.DegJoint4.Value;
                app.DegJoint4.Value = app.Joint4Slider.Value;
                app.RadJoint4.Value = deg2rad(app.DegJoint4.Value);
                app.RadJoint4.Value = deg2rad(app.Joint4Slider.Value);
                XEditFieldValueChanged(app);
                YEditFieldValueChanged(app);
                ZEditFieldValueChanged(app);
            end
            if(app.ModeButton.Value == 1)
                CalculateButtonPushed(app, event)
%                 app.Joint4Slider.Value = app.DegJoint4.Value;
%                 app.RadJoint4.Value = deg2rad(app.DegJoint4.Value);
            end
        end

        % Value changed function: Joint4Slider
        function Joint4SliderValueChanged(app, event)
            if(app.ModeButton.Value == 0)
                app.DegJoint4.Value = app.Joint4Slider.Value;
                app.Joint4Slider.Value = app.DegJoint4.Value;
                app.RadJoint4.Value = deg2rad(app.DegJoint4.Value);
                app.RadJoint4.Value = deg2rad(app.Joint4Slider.Value);
                XEditFieldValueChanged(app);
                YEditFieldValueChanged(app);
                ZEditFieldValueChanged(app);
            end
            if(app.ModeButton.Value == 1)
                CalculateButtonPushed(app, event)
            end
        end

        % Value changed function: DegJoint5
        function DegJoint5ValueChanged(app, event)
            if(app.ModeButton.Value == 0)
                if(app.DegJoint5.Value > 180)
                    app.DegJoint5.Value = 180;
                end
                if(app.DegJoint5.Value < -180)
                    app.DegJoint5.Value = -180;
                end
                app.Joint5Slider.Value = app.DegJoint5.Value;
                app.DegJoint5.Value = app.Joint5Slider.Value;
                app.RadJoint5.Value = deg2rad(app.DegJoint5.Value);
                app.RadJoint5.Value = deg2rad(app.Joint5Slider.Value);
                XEditFieldValueChanged(app);
                YEditFieldValueChanged(app);
                ZEditFieldValueChanged(app);
            end
            if(app.ModeButton.Value == 1)
                CalculateButtonPushed(app, event)
%                 app.Joint5Slider.Value = app.DegJoint5.Value;
%                 app.RadJoint5.Value = deg2rad(app.DegJoint5.Value);
            end
        end

        % Value changed function: Joint5Slider
        function Joint5SliderValueChanged(app, event)
            if(app.ModeButton.Value == 0)
                app.DegJoint5.Value = app.Joint5Slider.Value;
                app.Joint5Slider.Value = app.DegJoint5.Value;
                app.RadJoint5.Value = deg2rad(app.DegJoint5.Value);
                app.RadJoint5.Value = deg2rad(app.Joint5Slider.Value);
                XEditFieldValueChanged(app);
                YEditFieldValueChanged(app);
                ZEditFieldValueChanged(app);
            end
            if(app.ModeButton.Value == 1)
                CalculateButtonPushed(app, event)
            end
        end

        % Value changed function: DegJoint6
        function DegJoint6ValueChanged(app, event)
            if(app.ModeButton.Value == 0)
                if(app.DegJoint6.Value >= 125)
                    app.DegJoint6.Value = 125;
                end
                if(app.DegJoint6.Value <= -125)
                    app.DegJoint6.Value = -125;
                end
                app.Joint6Slider.Value = app.DegJoint6.Value;
                app.DegJoint6.Value = app.Joint6Slider.Value;
                app.RadJoint6.Value = deg2rad(app.DegJoint6.Value);
                app.RadJoint6.Value = deg2rad(app.Joint6Slider.Value);
                XEditFieldValueChanged(app);
                YEditFieldValueChanged(app);
                ZEditFieldValueChanged(app);
            end
            if(app.ModeButton.Value == 1)
                CalculateButtonPushed(app, event)
%                 app.Joint6Slider.Value = app.DegJoint6.Value;
%                 app.RadJoint6.Value = deg2rad(app.DegJoint6.Value);
            end
        end

        % Value changed function: Joint6Slider
        function Joint6SliderValueChanged(app, event)
            if(app.ModeButton.Value == 0)
                app.DegJoint6.Value = app.Joint6Slider.Value;
                app.Joint6Slider.Value = app.DegJoint6.Value;
                app.RadJoint6.Value = deg2rad(app.DegJoint6.Value);
                app.RadJoint6.Value = deg2rad(app.Joint6Slider.Value);
                XEditFieldValueChanged(app);
                YEditFieldValueChanged(app);
                ZEditFieldValueChanged(app);
            end
            if(app.ModeButton.Value == 1)
                CalculateButtonPushed(app, event)
            end
        end

        % Value changed function: DegJoint7
        function DegJoint7ValueChanged(app, event)
            if(app.ModeButton.Value == 0)
                if(app.DegJoint7.Value > 180)
                    app.DegJoint7.Value = 180;
                end
                if(app.DegJoint7.Value < -180)
                    app.DegJoint7.Value = -180;
                end
                app.Joint7Slider.Value = app.DegJoint7.Value;
                app.DegJoint7.Value = app.Joint7Slider.Value;
                app.RadJoint7.Value = deg2rad(app.DegJoint7.Value);
                app.RadJoint7.Value = deg2rad(app.Joint7Slider.Value);
                XEditFieldValueChanged(app);
                YEditFieldValueChanged(app);
                ZEditFieldValueChanged(app);
            end
            if(app.ModeButton.Value == 1)
                CalculateButtonPushed(app, event)
%                 app.Joint7Slider.Value = app.DegJoint7.Value;
%                 app.RadJoint7.Value = deg2rad(app.DegJoint7.Value);
            end
        end

        % Value changed function: Joint7Slider
        function Joint7SliderValueChanged(app, event)
            if(app.ModeButton.Value == 0)
                app.DegJoint7.Value = app.Joint7Slider.Value;
                app.Joint7Slider.Value = app.DegJoint7.Value;
                app.RadJoint7.Value = deg2rad(app.DegJoint7.Value);
                app.RadJoint7.Value = deg2rad(app.Joint7Slider.Value);
                XEditFieldValueChanged(app);
                YEditFieldValueChanged(app);
                ZEditFieldValueChanged(app);
            end
            if(app.ModeButton.Value == 1)
                CalculateButtonPushed(app, event)
            end
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            if(app.ModeButton.Value == 0)
                endEffector = CalculateEndEffector(app);
                x = endEffector(1,4);
                app.XEditField.Value = x;
            end
            if(app.ModeButton.Value == 1)
                if(app.XEditField.Value > 0.9352)
                    app.XEditField.Value = 0.9352;
                end
                if(app.XEditField.Value < -0.4811)
                    app.XEditField.Value = -0.4811;
                end
                value = app.XEditField.Value;
            end
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            if(app.ModeButton.Value == 0)
                endEffector = CalculateEndEffector(app);
                y = endEffector(2,4);
                app.YEditField.Value = y;
            end
            if(app.ModeButton.Value == 1)
                if(app.YEditField.Value > 0.9352)
                    app.YEditField.Value = 0.9352;
                end
                if(app.YEditField.Value < -0.9352)
                    app.YEditField.Value = -0.9352;
                end
                value = app.YEditField.Value;
            end
        end

        % Value changed function: ZEditField
        function ZEditFieldValueChanged(app, event)
            if(app.ModeButton.Value == 0)
                endEffector = CalculateEndEffector(app);
                z = endEffector(3,4);
                app.ZEditField.Value = z;
            end
            if(app.ModeButton.Value == 1)
                if(app.ZEditField.Value > 1.421)
                    app.ZEditField.Value = 1.421;
                end
                if(app.ZEditField.Value < -0.1867)
                    app.ZEditField.Value = -0.1867;
                end
                value = app.ZEditField.Value;
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            if(app.ModeButton.Value == 0)
                
            end
            if(app.ModeButton.Value == 1)
                disp("Im in mode 2");
                endEffector = transl(app.XEditField.Value, app.YEditField.Value, app.ZEditField.Value+0.5);
                joints = GetJointConfig(app, endEffector);
                app.DegJoint1.Value = rad2deg(joints(1));
                app.Joint1Slider.Value = rad2deg(joints(1));
                app.RadJoint1.Value = joints(1);
                app.GetJoint1Value();
                
                app.DegJoint2.Value = rad2deg(joints(2));
                app.Joint2Slider.Value = rad2deg(joints(2));
                app.RadJoint2.Value = joints(2);
                app.GetJoint2Value();
                
                app.DegJoint3.Value = rad2deg(joints(3));
                app.Joint3Slider.Value = rad2deg(joints(3));
                app.RadJoint3.Value = joints(3);
                app.GetJoint3Value();
                
                app.DegJoint4.Value = rad2deg(joints(4));
                app.Joint4Slider.Value = rad2deg(joints(4));
                app.RadJoint4.Value = joints(4);
                app.GetJoint4Value();
                
                app.DegJoint5.Value = rad2deg(joints(5));
                app.Joint5Slider.Value = rad2deg(joints(5));
                app.RadJoint5.Value = joints(5);
                app.GetJoint5Value();
                
                app.DegJoint6.Value = rad2deg(joints(6));
                app.Joint6Slider.Value = round(rad2deg(joints(6)));
                app.RadJoint6.Value = joints(6);
                app.GetJoint6Value();
                
                app.DegJoint7.Value = rad2deg(joints(7));
                app.Joint7Slider.Value = rad2deg(joints(7));
                app.RadJoint7.Value = joints(7);
                app.GetJoint7Value();
                %app.CalculateButton.ButtonPushedFcn
            end
        end

        % Value changed function: ModeButton
        function ModeButtonValueChanged(app, event)
            value = app.ModeButton.Value;
            
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {577, 577};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {307, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.EStopButton = uibutton(app.LeftPanel, 'state');
            app.EStopButton.ValueChangedFcn = createCallbackFcn(app, @EStopButtonValueChanged, true);
            app.EStopButton.Text = 'E-Stop';
            app.PowerSwitchLabel = uilabel(app.LeftPanel);
            app.PowerSwitchLabel.Text = 'Power';
            app.PowerSwitch = uiswitch(app.LeftPanel, 'slider');
            app.PowerSwitch.ValueChangedFcn = createCallbackFcn(app, @PowerSwitchValueChanged, true);
            app.BlockSliderLabel = uilabel(app.LeftPanel);
            app.BlockSliderLabel.Text = 'Block Slider';
            app.BlockSlider = uislider(app.LeftPanel);
            app.BlockSlider.ValueChangedFcn = createCallbackFcn(app, @BlockSliderValueChanged, true);
            app.BlockSlider.ValueChangingFcn = createCallbackFcn(app, @BlockSliderValueChanging, true);
            app.Lamp = uilamp(app.LeftPanel);
            app.StartButton = uibutton(app.LeftPanel, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.LightCurtainBlockSliderLabel = uilabel(app.LeftPanel);
            app.LightCurtainBlockSliderLabel.Text = 'Light Curtain Block Slider';
            app.LightCurtainBlockSlider = uislider(app.LeftPanel);
            app.LightCurtainBlockSlider.ValueChangedFcn = createCallbackFcn(app, @LightCurtainBlockSliderValueChanged, true);
            app.LightCurtainBlockSlider.ValueChangingFcn = createCallbackFcn(app, @LightCurtainBlockSliderValueChanging, true);
            app.ResetLightButton = uibutton(app.LeftPanel, 'state');
            app.ResetLightButton.ValueChangedFcn = createCallbackFcn(app, @ResetLightButtonValueChanged, true);
            app.ResetLightButton.Text = 'Reset Light';
            app.RightPanel = uipanel(app.GridLayout);
            app.Joint1Slider_2Label = uilabel(app.RightPanel);
            app.Joint1Slider_2Label.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.RightPanel);
            app.Joint1Slider.Limits = [-92 92];
            app.Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @Joint1SliderValueChanged, true);
            app.Joint2Slider_2Label = uilabel(app.RightPanel);
            app.Joint2Slider_2Label.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.RightPanel);
            app.Joint2Slider.Limits = [-87 70];
            app.Joint2Slider.ValueChangedFcn = createCallbackFcn(app, @Joint2SliderValueChanged, true);
            app.Joint3Slider_2Label = uilabel(app.RightPanel);
            app.Joint3Slider_2Label.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.RightPanel);
            app.Joint3Slider.Limits = [-180 180];
            app.Joint3Slider.ValueChangedFcn = createCallbackFcn(app, @Joint3SliderValueChanged, true);
            app.Joint4Slider_2Label = uilabel(app.RightPanel);
            app.Joint4Slider_2Label.Text = 'Joint 4';
            app.Joint4Slider = uislider(app.RightPanel);
            app.Joint4Slider.Limits = [-129 129];
            app.Joint4Slider.ValueChangedFcn = createCallbackFcn(app, @Joint4SliderValueChanged, true);
            app.Joint5Slider_2Label = uilabel(app.RightPanel);
            app.Joint5Slider_2Label.Text = 'Joint 5';
            app.Joint5Slider = uislider(app.RightPanel);
            app.Joint5Slider.Limits = [-180 180];
            app.Joint5Slider.ValueChangedFcn = createCallbackFcn(app, @Joint5SliderValueChanged, true);
            app.Joint6Slider_2Label = uilabel(app.RightPanel);
            app.Joint6Slider_2Label.Text = 'Joint 6';
            app.Joint7Slider_2Label = uilabel(app.RightPanel);
            app.Joint7Slider_2Label.Text = 'Joint 7';
            app.Joint7Slider = uislider(app.RightPanel);
            app.Joint7Slider.Limits = [-180 180];
            app.Joint7Slider.ValueChangedFcn = createCallbackFcn(app, @Joint7SliderValueChanged, true);
            app.TeachButton = uibutton(app.RightPanel, 'state');
            app.TeachButton.ValueChangedFcn = createCallbackFcn(app, @TeachButtonValueChanged, true);
            app.TeachButton.Text = 'Teach';
            app.Joint6Slider = uislider(app.RightPanel);
            app.Joint6Slider.Limits = [-125 125];
            app.Joint6Slider.ValueChangedFcn = createCallbackFcn(app, @Joint6SliderValueChanged, true);
            app.RadJoint1 = uieditfield(app.RightPanel, 'numeric');
            app.RadiansLabel = uilabel(app.RightPanel);
            app.RadiansLabel.Text = 'Radians';
            app.DegreesLabel = uilabel(app.RightPanel);
            app.DegreesLabel.Text = 'Degrees';
            app.DegJoint1 = uieditfield(app.RightPanel, 'numeric');
            app.DegJoint1.ValueChangedFcn = createCallbackFcn(app, @DegJoint1ValueChanged, true);
            app.RadJoint2 = uieditfield(app.RightPanel, 'numeric');
            app.RadJoint3 = uieditfield(app.RightPanel, 'numeric');
            app.RadJoint4 = uieditfield(app.RightPanel, 'numeric');
            app.RadJoint5 = uieditfield(app.RightPanel, 'numeric');
            app.RadJoint6 = uieditfield(app.RightPanel, 'numeric');
            app.RadJoint7 = uieditfield(app.RightPanel, 'numeric');
            app.DegJoint2 = uieditfield(app.RightPanel, 'numeric');
            app.DegJoint2.ValueChangedFcn = createCallbackFcn(app, @DegJoint2ValueChanged, true);
            app.DegJoint3 = uieditfield(app.RightPanel, 'numeric');
            app.DegJoint3.ValueChangedFcn = createCallbackFcn(app, @DegJoint3ValueChanged, true);
            app.DegJoint4 = uieditfield(app.RightPanel, 'numeric');
            app.DegJoint4.ValueChangedFcn = createCallbackFcn(app, @DegJoint4ValueChanged, true);
            app.DegJoint5 = uieditfield(app.RightPanel, 'numeric');
            app.DegJoint5.ValueChangedFcn = createCallbackFcn(app, @DegJoint5ValueChanged, true);
            app.DegJoint6 = uieditfield(app.RightPanel, 'numeric');
            app.DegJoint6.ValueChangedFcn = createCallbackFcn(app, @DegJoint6ValueChanged, true);
            app.DegJoint7 = uieditfield(app.RightPanel, 'numeric');
            app.DegJoint7.ValueChangedFcn = createCallbackFcn(app, @DegJoint7ValueChanged, true);
            app.XEditFieldLabel = uilabel(app.RightPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.RightPanel, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.YEditFieldLabel = uilabel(app.RightPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.RightPanel, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.ZEditFieldLabel = uilabel(app.RightPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.RightPanel, 'numeric');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @ZEditFieldValueChanged, true);
            app.EndEffectorLabel = uilabel(app.RightPanel);
            app.EndEffectorLabel.Text = 'End Effector';
            app.CalculateButton = uibutton(app.RightPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ModeButton = uibutton(app.RightPanel, 'state');
            app.ModeButton.ValueChangedFcn = createCallbackFcn(app, @ModeButtonValueChanged, true);
            app.ModeButton.Text = 'Mode';
            app.EditFieldLabel = uilabel(app.RightPanel);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.RightPanel, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end